classdef HUD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        O2ConsumptionLamp       matlab.ui.control.Lamp
        O2ConsumptionLampLabel  matlab.ui.control.Label
        ROIDist                 matlab.ui.control.Label
        LEMDist                 matlab.ui.control.Label
        CogLoadLamp             matlab.ui.control.Lamp
        CogLoadLampLabel        matlab.ui.control.Label
        HRLamp                  matlab.ui.control.Lamp
        HRLamp_2Label           matlab.ui.control.Label
        RRLamp                  matlab.ui.control.Lamp
        RRLampLabel             matlab.ui.control.Label
        TaskInstructions        matlab.ui.control.Label
        BatteryValue            matlab.ui.control.Label
        BatteryLifeLabel        matlab.ui.control.Label
        PressureValue           matlab.ui.control.Label
        SuitPressureLabel       matlab.ui.control.Label
        OxygenLevelTitle        matlab.ui.control.Label
        NominalLabel            matlab.ui.control.Label
        CO2ScrubberLabel        matlab.ui.control.Label
        LOWO2Lamp               matlab.ui.control.Lamp
        LOWO2LampLabel          matlab.ui.control.Label
        PlaySimulationButton    matlab.ui.control.Button
        MiniMap                 matlab.ui.control.UIAxes
        CompassAxes             matlab.ui.control.UIAxes
        UIOxygenAxes            matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIOxygenAxes.Title.Color = 'w';  % Change title font color to white
            % since apparently this isn't an option in design viewer, so we
            % have to manually do it  
            
            % manually changing some compass features
            app.CompassAxes.XColor = 'none';
            app.CompassAxes.YColor = 'none';
            app.CompassAxes.Color = 'none'; % Transparent background
            app.CompassAxes.Box = 'off'; % Remove box outline
            axis(app.CompassAxes, 'equal');
            hold(app.CompassAxes, 'on');
            
            % static compass rose
            theta = linspace(0, 2*pi, 100);
            x = cos(theta);
            y = sin(theta);
            plot(app.CompassAxes, x, y, 'w'); % circle outline for compass
            

            text(app.CompassAxes, 0, 1.2, 'N', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, 1.2, 0, 'E', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, 0, -1.2, 'S', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, -1.2, 0, 'W', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            
   
        end

        % Button pushed function: PlaySimulationButton
        function PlaySimulationButtonPushed(app, event)
            % When play simulation button is pushed, read in HUDFootage and
            % display it within our figure (UIAxes)
            filename = 'data/HUDFootage.mp4';
            v = VideoReader(filename);
            frame = readFrame(v);
            im_frame = imshow(frame, 'Parent', app.UIAxes);

            % Read in minimap and display it within app.MiniMap
            filenameMap = 'data/minimap.mp4';
            vMap = VideoReader(filenameMap);
            frameMap = readFrame(vMap);
            im_frameMap = imshow(frameMap, 'Parent', app.MiniMap);

            % load display data
            displayData = load('data/displayDataNominal.csv');
            oxygenData = displayData(2,:)'; % column vector made from row 2 of csv
            co2Data = displayData(3,:)'; % column vector made from row 3 of csv
            pressureData = displayData(4,:)';
            batteryData = displayData(5,:)';
            heartData = displayData(6,:)';
            respData = displayData(7,:)';
            o2consumeData = displayData(8,:)';
            cogloadData = displayData(9,:)';
            compassData = displayData(10,:)';
            lemDistData = displayData(11,:)';
            roiDistData = displayData(12,:)';
            %taskData = load('data/evaInstructions.csv');

            % load event data
            eventData = load('data/eventData.csv');
            % Change the name below to something like "hypoxiaTrigger" once
            % it actually represents something
            trigger1Data = eventData(2,:)'; % column vector made from row 2 of csv

            % Initialize dynamic heading pointer
            headingAngle = 180; % Initial heading, update dynamically. Initially west
            xPointer = [0, cosd(headingAngle)];
            yPointer = [0, sind(headingAngle)];
            compassPointer = plot(app.CompassAxes, xPointer, yPointer, 'r', 'LineWidth', 2);

            % Set font sizes
            %nominalFont = 12;
            %offnominalFont = 15;

            % Prepare oxygen bar
            hold(app.UIOxygenAxes, 'on');
            oxygenBar = rectangle(app.UIOxygenAxes,...
                'Position', [0, 0, 1, 0],...
                'FaceColor', 'g',...
                'EdgeColor','none');
            ylim(app.UIOxygenAxes, [0, 1]); % Normalize to percentage (0-1)
            hold(app.UIOxygenAxes, 'off');

            % Normalize oxygen data to [0, 1] for the bar
            %normalizedOxygen = max(0,min(oxygenData,1)); % making oxygen be 0-100 range
            
            % loop through entire video length
            frameIdx = 1;
            % Set initial alert flag equal to 0 (ie no alerts)
            alertFlag = 0;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
% LOOP THROUGH VIDEO
            while hasFrame(v) && frameIdx <= length(oxygenData)
                im_frame.CData = readFrame(v);
                im_frameMap.CData = readFrame(vMap);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
% EVENT TRIGGERS
                % if the alert boxes already exist, delete them
                if alertFlag == 1
                    % This will prevent us from re-drawing the same box on
                    % top of previous alert boxes and also make sure that
                    % we get rid of the alert after it's gone
                    delete(alertRect);
                    delete(alertText);
                end

                if trigger1Data(frameIdx) == 1

                    width = 600; % width:
                    height = 150; % height
                    xCenter = 1000; % Center alert box in middle of screen
                    yCenter = 150; % Center alert box in middle of screen
                    xLeft = xCenter - width/2;
                    yBottom = yCenter - height/2;
                    alertRect = rectangle(app.UIAxes,...
                        'Position', [xLeft, yBottom, width, height],...
                        'FaceColor', [1, 0, 0, 0.5],...
                        'EdgeColor', [1, 0, 0, 0.5],...
                        'Curvature',0.2);
                    alertText = text(app.UIAxes,xCenter,yCenter,"HYPOXIA ALERT",...
                        'HorizontalAlignment','center',...
                        'FontSize',20,...
                        'FontWeight','bold',...
                        'Color','w');
                    % Let us know in next run-through of the loop that we
                    % had an alert last loop
                    alertFlag = 1;

                    % Adaptive Interface Based on Alert
                    % For hypoxia for example, increase the font sizes
                    % Increase font sizes
                    %app.OxygenLevelTitle.FontSize = offnominalFont;
                    %app.NominalLabel.FontSize = offnominalFont;

                    % Set CO2 scrubber to invisible
                    %app.CO2ScrubberLabel.Visible = "off";
                    %app.NominalLabel.Visible = "off";
                    

                else
                    % Let us know in next run-through of the loop that we
                    % had NO alert last loop
                    alertFlag = 0;
                    % re-set the font sizes back to normal
                    %app.OxygenLevelTitle.FontSize = nominalFont;
                    %app.NominalLabel.FontSize = nominalFont;
                    % Set CO2 scrubber to invisible
                    %app.CO2ScrubberLabel.Visible = "on";
                    %app.NominalLabel.Visible = "on";
                end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
% DISPLAY DATA
    % Oxygen Data
                % Update oxygen bar
                currentLevel = oxygenData(frameIdx); % Get current oxygen level
                oxygenBar.Position = [0, 0, 1, currentLevel]; % Adjust bar height
               
                % Update the heading pointer dynamically (example: vary angle)
                headingAngle = mod(headingAngle + 1, 360); % Example: Increment heading
                xPointer = [0, cosd(headingAngle)];
                yPointer = [0, sind(headingAngle)];
                compassPointer.XData = xPointer;
                compassPointer.YData = yPointer;
                
                % Update lamp color and text based on oxygen level
                if currentLevel < 0.5  % If oxygen level is below 50%
                    app.LOWO2Lamp.Visible = "on";
                    app.LOWO2LampLabel.Visible = "on";
                    app.LOWO2Lamp.Color = 'red'; % Set lamp color to red for warning
                    app.LOWO2LampLabel.Text = 'LOW O2';
                else
                    app.LOWO2Lamp.Visible = "off";
                    app.LOWO2LampLabel.Visible = "off";
%                     app.LOWO2Lamp.Visible = 'green'; % Set lamp color to green if oxygen is safe
%                     app.LOWO2LampLabel.Text = "O2 Nominal";
                end
% CO2 Data
                % Update CO2 scrubber status (nominal is 1)
                if co2Data(frameIdx) == 0  % If CO2 value is 0
                    app.NominalLabel.FontColor = 'red'; 
                    app.NominalLabel.Text = 'Off-Nominal'; 
                else
                    app.NominalLabel.FontColor = 'green'; 
                    app.NominalLabel.Text = 'Nominal'; 
                end

% Pressure Data
                % Update pressure data
                app.PressureValue.Text = pressureData(frameIdx)+ "psi";

% Battery Data
                % Update battery data
                app.BatteryValue.Text = batteryData(frameIdx)+ "%";

% HR Data
                % Update HR data
                app.HRLamp_2Label.Text = "HR: " + heartData(frameIdx)+ "bpm";

% RR Data
                % Update RR data
                app.RRLampLabel.Text = "RR: " + respData(frameIdx)+ "brpm";

% O2 Consumption Rate Data
                % Update data
                app.O2ConsumptionLampLabel.Text = "O2 Use: " + o2consumeData(frameIdx)+ "psi/min";

% CogLoad Data
                % Update lamp color
                if cogloadData(frameIdx) == 0  % If cogload is good
                    app.CogLoadLamp.Color = 'green'; 
                    app.CogLoadLampLabel.Text = "Cog Load: Nominal";
                else % If cogload is bad
                    app.CogLoadLamp.Color = 'red'; 
                    app.CogLoadLampLabel.Text = "Cog Load: Off-Nominal";
                end

% Compass Data
                % Update compass
                %app.RRLampLabel.Text = respData(frameIdx)+ "brpm";

% LEM Distance Data
                % Update data
                app.LEMDist.Text = lemDistData(frameIdx)+ "m to LEM";

% ROI Distance Data
                % Update data
                app.ROIDist.Text = roiDistData(frameIdx)+ "m to ROI 1";


% Task Data
                % Add new task if applicable
                %app.RRLampLabel.Text = respData(frameIdx)+ "brpm";


% Ending Tasks
                % increment frame index
                frameIdx = frameIdx + 1;
                
                % Draw video frame onto axes
                drawnow % this refreshes the UI

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIOxygenAxes = uiaxes(app.UIFigure);
            zlabel(app.UIOxygenAxes, 'Z')
            app.UIOxygenAxes.XTick = [];
            app.UIOxygenAxes.YTick = [0 0.5 1];
            app.UIOxygenAxes.YTickLabel = {'0'; '50%'; '100%'};
            app.CompassAxes = uiaxes(app.UIFigure);
            app.CompassAxes.XTick = [];
            app.CompassAxes.YTick = [];
            app.MiniMap = uiaxes(app.UIFigure);
            app.MiniMap.XTick = [];
            app.MiniMap.YTick = [];
            app.PlaySimulationButton = uibutton(app.UIFigure, 'push');
            app.PlaySimulationButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySimulationButtonPushed, true);
            app.PlaySimulationButton.Text = 'Play Simulation';
            app.LOWO2LampLabel = uilabel(app.UIFigure);
            app.LOWO2LampLabel.Text = 'LOW O2';
            app.LOWO2Lamp = uilamp(app.UIFigure);
            app.CO2ScrubberLabel = uilabel(app.UIFigure);
            app.CO2ScrubberLabel.Text = 'CO2 Scrubber:';
            app.NominalLabel = uilabel(app.UIFigure);
            app.NominalLabel.Text = 'Nominal';
            app.OxygenLevelTitle = uilabel(app.UIFigure);
            app.OxygenLevelTitle.Text = 'O2 Level';
            app.SuitPressureLabel = uilabel(app.UIFigure);
            app.SuitPressureLabel.Text = 'Suit Pressure:';
            app.PressureValue = uilabel(app.UIFigure);
            app.PressureValue.Text = 'XXpsi';
            app.BatteryLifeLabel = uilabel(app.UIFigure);
            app.BatteryLifeLabel.Text = 'Battery Life:';
            app.BatteryValue = uilabel(app.UIFigure);
            app.BatteryValue.Text = 'XX%';
            app.TaskInstructions = uilabel(app.UIFigure);
            app.TaskInstructions.Text = {'Proceed to LEM '; 'Gather supplies Proceed to ROI 1'; 'Identify sample'; 'Drill sample'; 'Return to LEM'};
            app.RRLampLabel = uilabel(app.UIFigure);
            app.RRLampLabel.Text = 'RR';
            app.RRLamp = uilamp(app.UIFigure);
            app.HRLamp_2Label = uilabel(app.UIFigure);
            app.HRLamp_2Label.Text = 'HR';
            app.HRLamp = uilamp(app.UIFigure);
            app.CogLoadLampLabel = uilabel(app.UIFigure);
            app.CogLoadLampLabel.Text = 'Cog. Load';
            app.CogLoadLamp = uilamp(app.UIFigure);
            app.LEMDist = uilabel(app.UIFigure);
            app.LEMDist.Text = 'XXm to LEM';
            app.ROIDist = uilabel(app.UIFigure);
            app.ROIDist.Text = 'XXm to ROI1';
            app.O2ConsumptionLampLabel = uilabel(app.UIFigure);
            app.O2ConsumptionLampLabel.Text = 'O2 Consumption';
            app.O2ConsumptionLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HUD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end